classdef MorseCodeTranslator < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                      matlab.ui.Figure
		PinstatusLabel                matlab.ui.control.Label
		Label                         matlab.ui.control.Label
		hightolerancemultiplierLabel  matlab.ui.control.Label
		tfHighTolerance               matlab.ui.control.NumericEditField
		tfLowTolerance                matlab.ui.control.NumericEditField
		lowtolerancemultiplierLabel   matlab.ui.control.Label
		lblConnectionError            matlab.ui.control.Label
		btnPinStatusIndicator         matlab.ui.control.Button
		btnListen                     matlab.ui.control.Button
		lblPort                       matlab.ui.control.Label
		lblBoard                      matlab.ui.control.Label
		tfMessage                     matlab.ui.control.EditField
		ddPortSelection               matlab.ui.control.DropDown
		ddBoardSelection              matlab.ui.control.DropDown
		lblMorseCodeTranslator        matlab.ui.control.Label
		btnConnect                    matlab.ui.control.Button
	end

	
	methods (Access = private)
		
		function character = check_string_against_dictionary(app, word_book, input_string)
			character = '';
			%dictionary_length = size(dictionary(:,2))
			for inc = 1:38
				if(strcmp(input_string, word_book{inc,2}))
					character = word_book{inc, 1};
				end
			end
		end
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			%declare tuneable time constants in milliseconds
			app.UIFigure.UserData.DIT = 0.100; %base unit of 100 ms
			app.UIFigure.UserData.DAH = 3*app.UIFigure.UserData.DIT;
			app.UIFigure.UserData.SPACE = app.UIFigure.UserData.DIT;
			app.UIFigure.UserData.SPACE_LETTER = 3*app.UIFigure.UserData.DIT;
			app.UIFigure.UserData.SPACE_WORD = 7*app.UIFigure.UserData.DIT;
			%tolerance multipliers are based on the time duration they are multiplied with
			%app.UIFigure.UserData.TOLERANCE_LOW = 0.5;
			%app.UIFigure.UserData.TOLERANCE_HIGH = 2;

			filename = "morseTable.txt";
			opts = detectImportOptions(filename);
			opts.VariableTypes = {'string', 'string'};
			opts.DataLines = [1 38];
			
			app.UIFigure.UserData.word_book = readtable(filename, opts);
			
			board_list = readlines('board_types.txt');
			app.ddBoardSelection.Items = board_list;
			port_list = serialportlist';
			app.ddPortSelection.Items = port_list;
		end

		% Button pushed function: btnConnect
		function btnConnectPushed(app, event)
			try
				com_port = app.ddPortSelection.Value;
				board_type = app.ddBoardSelection.Value;
				app.UIFigure.UserData.a = arduino(com_port, board_type);
			catch ME
				app.lblConnectionError.Visible = true;%catch any errors and give the user a vague error message that doesn't bother telling what part failed
			end

			if(exist('app.UIFigure.UserData.a', 'var'))
				app.lblConnectionError.Visible = false;%clear error message if there is one
			end
			app.btnListen.Enable = "on";%enable the listen button now that a board and port has been selected
		end

		% Button pushed function: btnListen
		function btnListenPushed(app, event)
			%update GUI components
			app.btnListen.Enable = "off";
			app.btnConnect.Enable = "off";
			%app.tfMessage.Value = 'waiting for start of message...';
			app.btnPinStatusIndicator.Text = '';
			
			%create message loop variables
			current_dit_dah_vector = '';
			reading_high = false;
			reading_low = false;
			translation_attempts = 0;
			hold_for_first_message = false;
			
			%load dictionary and timing variables from UserData and UI components
			DIT = app.UIFigure.UserData.DIT;
			DAH = app.UIFigure.UserData.DAH;
			SPACE = app.UIFigure.UserData.SPACE;
			SPACE_LETTER = app.UIFigure.UserData.SPACE_LETTER;
			SPACE_WORD = app.UIFigure.UserData.SPACE_WORD;
			TOLERANCE_LOW = app.tfLowTolerance.Value;
			TOLERANCE_HIGH = app.tfHighTolerance.Value;
			word_book = app.UIFigure.UserData.word_book;

			%wrap this entire loop in a try catch to handle comm error when Berl unplugs the board
			while(translation_attempts < 4)
				pin_status = app.UIFigure.UserData.a.readDigitalPin("D9");
				if(pin_status)%pin value high
					if(reading_high)% status was already reading high, continue reading high
						app.btnPinStatusIndicator.BackgroundColor = 'k';
					else%first time reading high, swap monitor booleans and analyze low time
						reading_high = true;
						reading_low = false;
						time_low = toc;
						%process the time duration signal was low
						if((time_low > (SPACE*TOLERANCE_LOW)) && (time_low < (SPACE*TOLERANCE_HIGH)))
							%do nothing, letter is not finished yet
						elseif((time_low > (SPACE_LETTER*TOLERANCE_LOW)) &&(time_low < (SPACE_WORD*TOLERANCE_HIGH)))
							%signal received for end of character, check against dictionary
							converted_character =check_string_against_dictionary(app, word_book, current_dit_dah_vector)
								%check for special characters
								if(strcmp(converted_character, 'AR'))
									fprintf('special char AR found, message complete')
									%increment translation attempts here instead of at the start of the message to prevent text from being cleared on
									%the last attempt
									translation_attempts = translation_attempts +1;
								elseif(strcmp(converted_character, 'KA'))
									hold_for_first_message = false;
									fprintf('special char KA found, start of message')
									app.tfMessage.Value = '';%reset text, on first loop this also clears the waiting message
								else%if not a special character, check that we are allowed to read the message
									if(~hold_for_first_message)
										app.tfMessage.Value = strcat(app.tfMessage.Value, converted_character);
									end
								end
							if(time_low > (SPACE_WORD*TOLERANCE_LOW))%low time already indicated that it was at minimum an end of character, check if it was an end of word and add a space if needed
								if(~hold_for_first_message)%check that we are allowed to read the message at this point
									app.tfMessage.Value = strcat(app.tfMessage.Value, ' ');
								end
							end
							current_dit_dah_vector = '';%clear vector after processing
						end
						tic;
					end
				else
					if(reading_low)
						app.btnPinStatusIndicator.BackgroundColor = 'w';
					else%first time reading low, swap monitor booleans and analyze high time
						reading_high = false;
						reading_low = true;
						time_high = toc;
						%if the time high was greater than DAH length times low tolerance, and lower than DAH length times high tolerance
						if((time_high > (DAH*TOLERANCE_LOW)) && (time_high < (DAH*TOLERANCE_HIGH)))
							current_dit_dah_vector(end+1) = '-';
						elseif((time_high > (DIT*TOLERANCE_LOW)) && (time_high < (DIT*TOLERANCE_HIGH)))
							current_dit_dah_vector(end+1) = '.';
						end
						tic
					end
				end
			end

			app.btnListen.Enable = "on";
			app.btnConnect.Enable = "on";
			app.btnPinStatusIndicator.Text = 'message complete';
			app.btnPinStatusIndicator.BackgroundColor = [0.96,0.96,0.96];
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.btnConnect = uibutton(app.UIFigure, 'push');
			app.btnConnect.ButtonPushedFcn = createCallbackFcn(app, @btnConnectPushed, true);
			app.btnConnect.Text = 'Connect';
			app.lblMorseCodeTranslator = uilabel(app.UIFigure);
			app.lblMorseCodeTranslator.Text = 'Morse Code Translator';
			app.ddBoardSelection = uidropdown(app.UIFigure);
			app.ddBoardSelection.Items = {};
			app.ddBoardSelection.Value = {};
			app.ddPortSelection = uidropdown(app.UIFigure);
			app.ddPortSelection.Items = {};
			app.ddPortSelection.Value = {};
			app.tfMessage = uieditfield(app.UIFigure, 'text');
			app.tfMessage.Editable = 'off';
			app.lblBoard = uilabel(app.UIFigure);
			app.lblBoard.Text = 'Board';
			app.lblPort = uilabel(app.UIFigure);
			app.lblPort.Text = 'Port';
			app.btnListen = uibutton(app.UIFigure, 'push');
			app.btnListen.ButtonPushedFcn = createCallbackFcn(app, @btnListenPushed, true);
			app.btnListen.Enable = 'off';
			app.btnListen.Text = 'Listen';
			app.btnPinStatusIndicator = uibutton(app.UIFigure, 'push');
			app.btnPinStatusIndicator.Text = 'waiting';
			app.lblConnectionError = uilabel(app.UIFigure);
			app.lblConnectionError.Visible = 'off';
			app.lblConnectionError.Text = 'Error connecting to board or port, check settings and try again';
			app.lowtolerancemultiplierLabel = uilabel(app.UIFigure);
			app.lowtolerancemultiplierLabel.Text = 'low tolerance multiplier';
			app.tfLowTolerance = uieditfield(app.UIFigure, 'numeric');
			app.tfLowTolerance.Limits = [0.5 1];
			app.tfLowTolerance.Value = 0.7;
			app.tfHighTolerance = uieditfield(app.UIFigure, 'numeric');
			app.tfHighTolerance.Limits = [1 2];
			app.tfHighTolerance.Value = 1.3;
			app.hightolerancemultiplierLabel = uilabel(app.UIFigure);
			app.hightolerancemultiplierLabel.Text = 'high tolerance multiplier';
			app.Label = uilabel(app.UIFigure);
			app.Label.Text = {'default tolerance values allow +/- 30% deviation from timing specification'; 'these values may need to be adjusted based on PC/communication performance'};
			app.PinstatusLabel = uilabel(app.UIFigure);
			app.PinstatusLabel.Text = 'Pin status:';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = MorseCodeTranslator

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end